classdef RobotControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        EditField_6          matlab.ui.control.NumericEditField
        EditField_5          matlab.ui.control.NumericEditField
        EditField_4          matlab.ui.control.NumericEditField
        EditField_3          matlab.ui.control.NumericEditField
        EditField_2          matlab.ui.control.NumericEditField
        EditField            matlab.ui.control.NumericEditField
        ZMinusButton         matlab.ui.control.Button
        ZPlusButton          matlab.ui.control.Button
        Link6Slider          matlab.ui.control.Slider
        Link6SliderLabel     matlab.ui.control.Label
        Link5Slider          matlab.ui.control.Slider
        Link5SliderLabel     matlab.ui.control.Label
        Link4Slider          matlab.ui.control.Slider
        Link4SliderLabel     matlab.ui.control.Label
        Link3Slider          matlab.ui.control.Slider
        Link3SliderLabel     matlab.ui.control.Label
        Link2Slider          matlab.ui.control.Slider
        Link2SliderLabel     matlab.ui.control.Label
        Link1Slider          matlab.ui.control.Slider
        Link1SliderLabel     matlab.ui.control.Label
        XMinusButton         matlab.ui.control.Button
        YPlusButton          matlab.ui.control.Button
        YMinusButton         matlab.ui.control.Button
        XPlusButton          matlab.ui.control.Button
        CenterPanel          matlab.ui.container.Panel
        GridLayout4          matlab.ui.container.GridLayout
        LoadRobotButton      matlab.ui.control.Button
        ReengageSwitch       matlab.ui.control.ToggleSwitch
        ReengageSwitchLabel  matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        EmergencyStopButton  matlab.ui.control.StateButton
        UIAxes               matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        Link6Gauge           matlab.ui.control.Gauge
        Link6GaugeLabel      matlab.ui.control.Label
        Link5Gauge           matlab.ui.control.Gauge
        Link5GaugeLabel      matlab.ui.control.Label
        Link4Gauge           matlab.ui.control.Gauge
        Link4GaugeLabel      matlab.ui.control.Label
        Link3Gauge           matlab.ui.control.Gauge
        Link3GaugeLabel      matlab.ui.control.Label
        Link2Gauge           matlab.ui.control.Gauge
        Link2GaugeLabel      matlab.ui.control.Label
        Link1Gauge           matlab.ui.control.LinearGauge
        Link1GaugeLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        modelDobot % The loaded Dobot
        qz = [0     0     0     0     0     0];
        qr = [0	    pi/2  -pi/2	0	  0	    0];
    end
    
    methods (Access = private)
        
        function EnableSliders(app, bool)
            app.Link1Slider.Enable = bool;
            app.Link2Slider.Enable = bool;
            app.Link3Slider.Enable = bool;
            app.Link4Slider.Enable = bool;
            app.Link5Slider.Enable = bool;
            app.Link6Slider.Enable = bool;
        end
        
        function EnableCartesianButtons(app, bool)
            app.XPlusButton.Enable = bool;
            app.XMinusButton.Enable = bool;
            app.YPlusButton.Enable = bool;
            app.YMinusButton.Enable = bool;
            app.ZPlusButton.Enable = bool;
            app.ZMinusButton.Enable = bool;
        end
        
        function MoveCartesian(app, dimension, direction)
            switch dimension
                case 'x'
                    a = 1;
                case 'y'
                    a = 2;
                case 'z'
                    a = 3;
                otherwise
                    disp("Invalid dimension requested - x used as default");
                    a = 1;
            end

            q = app.modelDobot.model.getpos;
            tr = app.modelDobot.model.fkine(q);

            switch direction
                case 'positive'
                    tr(a,4) = tr(a,4) + 0.01;
                case 'negative'
                    tr(a,4) = tr(a,4) - 0.01;
                otherwise
                    disp("Invalid direction requested - positive used as default");
                    tr(a,4) = tr(a,4) + 0.01;
            end

            newQ = app.modelDobot.model.ikcon(tr,q);
            app.modelDobot.model.animate(newQ);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadRobotButton
        function LoadRobotButtonPushed(app, event)
            axes(app.UIAxes);
            cla(app.UIAxes)
            
            % Make, save and plot some robots            
%             figure('visible','off'); % Makes the figure invisible when loading
            ax = gca;
            app.modelDobot = LinearDobot(false);
            %app.modelDobot.base = transl([0.5,0,0]);
            %plot(app.modelDobot,app.qr)
            app.modelDobot.model.animate(app.qz);
            drawnow();
            hold on;            
            
            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Transfer all the children across to the uiaxes parent EXCEPT
            % the green floors
            while 0 < length(ax.Children)                
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))
            
            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];
            
            % Turn off load button and enable the move buttons and sliders
            app.EmergencyStopButton.Enable = true;
            EnableCartesianButtons(app,true);
            EnableSliders(app,true);
        end

        % Value changed function: EmergencyStopButton
        function EmergencyStopButtonValueChanged(app, event)
            value = app.EmergencyStopButton.Value;
            if value
                app.Lamp.Color = 'red';
                EnableCartesianButtons(app,false);
                EnableSliders(app,false);
            else 
                app.Lamp.Color = 'green';
                EnableCartesianButtons(app,true);
                EnableSliders(app,true);
            end
        end

        % Button pushed function: XPlusButton
        function XPlusButtonPushed(app, event)
            MoveCartesian(app,'x','positive');
        end

        % Button pushed function: XMinusButton
        function XMinusButtonPushed(app, event)
            MoveCartesian(app,'x','negative');
        end

        % Button pushed function: YPlusButton
        function YPlusButtonPushed(app, event)
            MoveCartesian(app,'y','positive');
        end

        % Button pushed function: YMinusButton
        function YMinusButtonPushed(app, event)
            MoveCartesian(app,'y','negative');
        end

        % Button pushed function: ZPlusButton
        function ZPlusButtonPushed(app, event)
            MoveCartesian(app,'z','positive');
        end

        % Button pushed function: ZMinusButton
        function ZMinusButtonPushed(app, event)
            MoveCartesian(app,'z','negative');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x', 172};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.XPlusButton = uibutton(app.GridLayout2, 'push');
            app.XPlusButton.ButtonPushedFcn = createCallbackFcn(app, @XPlusButtonPushed, true);
            app.XPlusButton.Enable = 'off';
            app.XPlusButton.Text = '+X';
            app.YMinusButton = uibutton(app.GridLayout2, 'push');
            app.YMinusButton.ButtonPushedFcn = createCallbackFcn(app, @YMinusButtonPushed, true);
            app.YMinusButton.Enable = 'off';
            app.YMinusButton.Text = '+Y';
            app.YPlusButton = uibutton(app.GridLayout2, 'push');
            app.YPlusButton.ButtonPushedFcn = createCallbackFcn(app, @YPlusButtonPushed, true);
            app.YPlusButton.Enable = 'off';
            app.YPlusButton.Text = '-Y';
            app.XMinusButton = uibutton(app.GridLayout2, 'push');
            app.XMinusButton.ButtonPushedFcn = createCallbackFcn(app, @XMinusButtonPushed, true);
            app.XMinusButton.Enable = 'off';
            app.XMinusButton.Text = '-X';
            app.Link1SliderLabel = uilabel(app.GridLayout2);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.GridLayout2);
            app.Link1Slider.Enable = 'off';
            app.Link2SliderLabel = uilabel(app.GridLayout2);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.GridLayout2);
            app.Link2Slider.Enable = 'off';
            app.Link3SliderLabel = uilabel(app.GridLayout2);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.GridLayout2);
            app.Link3Slider.Enable = 'off';
            app.Link4SliderLabel = uilabel(app.GridLayout2);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.GridLayout2);
            app.Link4Slider.Enable = 'off';
            app.Link5SliderLabel = uilabel(app.GridLayout2);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.GridLayout2);
            app.Link5Slider.Enable = 'off';
            app.Link6SliderLabel = uilabel(app.GridLayout2);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.GridLayout2);
            app.Link6Slider.Enable = 'off';
            app.ZPlusButton = uibutton(app.GridLayout2, 'push');
            app.ZPlusButton.ButtonPushedFcn = createCallbackFcn(app, @ZPlusButtonPushed, true);
            app.ZPlusButton.Enable = 'off';
            app.ZPlusButton.Text = '+Z';
            app.ZMinusButton = uibutton(app.GridLayout2, 'push');
            app.ZMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ZMinusButtonPushed, true);
            app.ZMinusButton.Enable = 'off';
            app.ZMinusButton.Text = '-Z';
            app.EditField = uieditfield(app.GridLayout2, 'numeric');
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_2.Enable = 'off';
            app.EditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_3.Enable = 'off';
            app.EditField_4 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_5.Enable = 'off';
            app.EditField_6 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_6.Enable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Dobot Magician')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EmergencyStopButton = uibutton(app.GridLayout4, 'state');
            app.EmergencyStopButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopButtonValueChanged, true);
            app.EmergencyStopButton.Enable = 'off';
            app.EmergencyStopButton.Icon = 'stop_button.jpg';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.Lamp = uilamp(app.GridLayout4);
            app.ReengageSwitchLabel = uilabel(app.GridLayout4);
            app.ReengageSwitchLabel.Enable = 'off';
            app.ReengageSwitchLabel.Text = 'Re-engage';
            app.ReengageSwitch = uiswitch(app.GridLayout4, 'toggle');
            app.ReengageSwitch.Enable = 'off';
            app.LoadRobotButton = uibutton(app.GridLayout4, 'push');
            app.LoadRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotButton.Text = 'Load Robot';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.Link1GaugeLabel = uilabel(app.GridLayout3);
            app.Link1GaugeLabel.Text = 'Link 1';
            app.Link1Gauge = uigauge(app.GridLayout3, 'linear');
            app.Link2GaugeLabel = uilabel(app.GridLayout3);
            app.Link2GaugeLabel.Text = 'Link 2';
            app.Link2Gauge = uigauge(app.GridLayout3, 'circular');
            app.Link3GaugeLabel = uilabel(app.GridLayout3);
            app.Link3GaugeLabel.Text = 'Link 3';
            app.Link3Gauge = uigauge(app.GridLayout3, 'circular');
            app.Link4GaugeLabel = uilabel(app.GridLayout3);
            app.Link4GaugeLabel.Text = 'Link 4';
            app.Link4Gauge = uigauge(app.GridLayout3, 'circular');
            app.Link5GaugeLabel = uilabel(app.GridLayout3);
            app.Link5GaugeLabel.Text = 'Link 5';
            app.Link5Gauge = uigauge(app.GridLayout3, 'circular');
            app.Link6GaugeLabel = uilabel(app.GridLayout3);
            app.Link6GaugeLabel.Text = 'Link 6';
            app.Link6Gauge = uigauge(app.GridLayout3, 'circular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end